classdef Audio_Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AxisPanel                     matlab.ui.container.Panel
        OutputFreqAxes                matlab.ui.control.UIAxes
        InputFreqAxes                 matlab.ui.control.UIAxes
        OutputTimeAxes                matlab.ui.control.UIAxes
        InputTimeAxes                 matlab.ui.control.UIAxes
        SamplingRateEditField_2       matlab.ui.control.NumericEditField
        SamplingRateEditField_2Label  matlab.ui.control.Label
        BandtoPlotDropDown            matlab.ui.control.DropDown
        BandtoPlotDropDownLabel       matlab.ui.control.Label
        LocationEditField             matlab.ui.control.EditField
        LocationEditFieldLabel        matlab.ui.control.Label
        PlayButton                    matlab.ui.control.Button
        doubleFsButton                matlab.ui.control.Button
        halfFsButton                  matlab.ui.control.Button
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        CustomButton                  matlab.ui.control.RadioButton
        StandardButton                matlab.ui.control.RadioButton
        EnterButton                   matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        gain_20k                      matlab.ui.control.Slider
        KHzLabel_4                    matlab.ui.control.Label
        gain_14k                      matlab.ui.control.Slider
        KhzLabel                      matlab.ui.control.Label
        gain_12k                      matlab.ui.control.Slider
        KHzLabel_2                    matlab.ui.control.Label
        gain_6k                       matlab.ui.control.Slider
        KHzLabel_3                    matlab.ui.control.Label
        gain_610                      matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        gain_300                      matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        gain_170                      matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        FreuquencyDomainPanel         matlab.ui.container.Panel
        BeforeLabel_2                 matlab.ui.control.Label
        AfterLabel                    matlab.ui.control.Label
        PhaseLabel                    matlab.ui.control.Label
        MagnitudeLabel                matlab.ui.control.Label
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        FrequencyBandGainsPanel       matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        gain_3k                       matlab.ui.control.Slider
        KHzLabel                      matlab.ui.control.Label
        gain_1005                     matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        FilterTypeButtonGroup         matlab.ui.container.ButtonGroup
        IIRButton                     matlab.ui.control.RadioButton
        FIRButton                     matlab.ui.control.RadioButton
        BrowseButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        y % resampled wave
        fir_order = 40
        iir_order = 4
        fs % sampling frequency
        t % time
        fm % 
        fo % sampling rate output
        Ns % number of samples
        BandGains = ones(1,9); % gains of each band
        BandEdges;
        newfs % inputted sampling rate
        new_signal % Description
        Mode="Standard" %App Mode: Standard or Custom
        CustomBands;
        CustomGains;
        FilterType;
        FilterTypeFIR;
        FilterTypeIIR;
        FilterOrder;
        FilterSubType;
        OutputSignal;

    end
    
    methods (Access = private)
        
        function x=showCustomBandDialog(app)
           % Create a new figure (popup)
        d = uifigure('Name', 'Enter Custom Bands', 'Position', [500 500 300 200]);
    
        % Label
        uilabel(d, ...
            'Text', 'Enter frequency edges (comma-separated):', ...
            'Position', [20 130 260 30]);
    
        % Input field
        bandField = uieditfield(d, ...
            'Position', [20 100 260 25], ...
            'Tag', 'BandField');
    
        % Confirm Button
        uibutton(d, ...
            'Text', 'Confirm', ...
            'Position', [100 50 100 30], ...
            'ButtonPushedFcn', @(btn,event)confirmBands(app, d, bandField)); 
        x=d;
            end
            
        function confirmBands(app, dialog, bandField)
                str = bandField.Value;
        edges = str2num(str);  % Parse comma-separated input
    
        % Validation
            if isempty(edges) || edges(1) ~= 0 || edges(end) ~= 20000 || ...
           length(edges) < 6 || length(edges) > 11 || any(diff(edges) <= 0)
            uialert(dialog, 'Input must be 0–20kHz, 5–10 bands, ascending.', 'Invalid Input');
            return;
            end

            % Store the bands and close dialog
            app.CustomBands = edges;
            disp("Custom bands accepted:");
            disp(edges);
            close(dialog);
        end

        function showCustomGainDialogBox(app)
            numBands = length(app.CustomBands) - 1;
            prompts = cell(1, numBands);  % Preallocate
            default_vals = cell(1, numBands);

            for i = 1:numBands
                prompt{i} = sprintf('Enter gain for band %d (dB):', i);
                default_vals{i} = '0';
            end
            dlg_title = 'Gain (in dB)';
            num_lines = 1;
            
            answer = inputdlg(prompts, dlg_title, num_lines, default_vals);
            if isempty(answer)
                errordlg('Gain input canceled by user.');
                return;
            end

            gains = zeros(1, length(answer));
            for i = 1:length(answer)
                val = str2double(answer{i});  % Convert String to A number tto store the gain
                if isnan(val)
                    errordlg(sprintf('Invalid gain input at band %d', i));
                    return;
                end
                gains(i) = val;
            end
            app.CustomGains=gains;
            disp("Custom bands accepted:");
            disp(gains);
        end

  function filterTypeIIR = getIIRFilterType(app)
    % Loop to ensure the user selects a valid filter type
     validFilters = {'BUTTERWORTH', 'CHEBYSHEV I', 'CHEBYSHEV II'};
            while true
                prompt = {'Select IIR filter type (Butterworth, Chebyshev I, Chebyshev II):'};
                dlg_title = 'IIR Filter Type';
                num_lines = 1;
                default_answer = {'Butterworth'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterTypeIIR = ''; % No selection made, exit
                    return;
                end
        
        % Extract the filter type from the input dialog
        filterTypeIIR = answer{1};
        
        % Convert user input to uppercase to avoid case sensitivity issues
        filterTypeIIR = strtrim(filterTypeIIR); % Remove leading/trailing spaces
       
        
        % Check if the selected filter type is valid (case-insensitive)
                 if ismember(upper(filterTypeIIR), validFilters)
                    break;  % Exit the loop if the filter type is valid
                else
            % Display an error dialog and prompt the user to try again
            h= errordlg('Invalid Filter Type. Please select a valid filter.', 'Error');
            waitfor(h);
                end
            end
    
    % Continue with the selected valid filter type
    disp(['Selected IIR Filter Type: ', filterTypeIIR]);
        end
        
      function filterTypeFIR = getFIRFilterType(app)
    % Loop to ensure the user selects a valid filter type
     validFilters = {'HAMMING', 'HANNING', 'BLACKMAN'};
            while true
                prompt = {'Select FIR filter type (Hamming, Hanning, or Blackman):'};
                dlg_title = 'FIR Filter Type';
                num_lines = 1;
                default_answer = {'Hamming'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterTypeFIR = ''; % No selection made, exit
                    return;
                end
        
        % Extract the filter type from the input dialog
        filterTypeFIR = answer{1};
        
        % Convert user input to uppercase to avoid case sensitivity issues
        filterTypeFIR = strtrim(filterTypeFIR); % Remove leading/trailing spaces
       
        
        % Check if the selected filter type is valid (case-insensitive)
                 if ismember(upper(filterTypeFIR), validFilters)
                    break;  % Exit the loop if the filter type is valid
                else
            % Display an error dialog and prompt the user to try again
            h= errordlg('Invalid Filter Type. Please select a valid filter.', 'Error');
            waitfor(h);
                end
            end
    
    % Continue with the selected valid filter type
    disp(['Selected FIR Filter Type: ', filterTypeFIR]);
      end
       function filterOrder = getFilterOrder(app)
   
            while true
                prompt = {'Enter filter order (positive integer):'};
                dlg_title = 'Filter Order';
                num_lines = 1;
                default_answer = {'5'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterOrder=[];
                    return;
                end
        
        
                orderVal = str2double(answer{1});
        
                if isnumeric(orderVal) && isfinite(orderVal) && orderVal > 0 && mod(orderVal, 1) == 0
                filterOrder = orderVal;
                return;
                else
                 h = errordlg('Invalid filter order. Please enter a positive integer.', 'Error');
                 waitfor(h);  % Wait until user closes error dialog
                end
            end
       end
       function filters = designIIRFilters(app)
        numBands = length(app.CustomBands) - 1;
        filters = cell(1, numBands);
        
            for i = 1:numBands
            bandStart = app.CustomBands(i);       % Start frequency of the band
            bandEnd = app.CustomBands(i + 1);     % End frequency of the band
            
            % Design an IIR filter for each band
            if i == 1
                [b, a] = butter(4, bandEnd / (app.fs / 2));  % 4th order lowpass filter
            elseif i > 1 && i < numBands
                [b, a] = butter(4, [bandStart, bandEnd] / (app.fs / 2), 'bandpass');
            else
                [b, a] = butter(4, bandStart / (app.fs / 2), 'high');  % Highpass filter
            end
            
            % Store the filter coefficients
            filters{i} = struct('b', b, 'a', a, 'startFreq', bandStart, 'endFreq', bandEnd);
            end
       end

       function plot(app)
  
            t = linspace(0, length(app.y)/app.fs, length(app.y));
            L = length(app.y);  
        
            plot(app.InputTimeAxes, t, app.y);
            xlabel(app.InputTimeAxes, 'Time (s)');
            ylabel(app.InputTimeAxes, 'Amplitude');
            title(app.InputTimeAxes, 'Input Signal - Time Domain');

          
            t_new = (0:L-1) / (app.newfs );
            
            plot(app.OutputTimeAxes, t_new, app.y);
            xlabel(app.OutputTimeAxes, 'Time (s)');
            ylabel(app.OutputTimeAxes, 'Amplitude');
            title(app.OutputTimeAxes, 'Output Signal - Time Domain');

            
            % Frequency domain input signal
            f_axis_in = linspace(-app.fs/2, app.fs/2, L);
            Y_fft = fftshift(fft(app.y)/L);
            fmag = abs(Y_fft);
        
            plot(app.InputFreqAxes, f_axis_in, fmag);
            xlabel(app.InputFreqAxes, 'Frequency (Hz)');
            ylabel(app.InputFreqAxes, 'Magnitude');
            title(app.InputFreqAxes, 'Input Signal - Frequency Domain');
                   
             % Frequency domain output signal
            f_axis_out = linspace(-app.newfs/2, app.newfs/2, L);
            Y_out_fft = fftshift(fft(app.y)/L);
            fmag_out = abs(Y_out_fft);
        
            plot(app.OutputFreqAxes, f_axis_out, fmag_out);
            xlabel(app.OutputFreqAxes, 'Frequency (Hz)');
            ylabel(app.OutputFreqAxes, 'Magnitude');
            title(app.OutputFreqAxes, 'Output Signal - Frequency Domain');
       end

       function applyFilter(app)
                 % Step 1: Check and set the sampling rate (default if not specified)
    if isempty(app.fs) || app.fs <= 0
        app.fs = 44100; % Set default sampling rate if not provided (e.g., 44.1kHz)
    end
 % Initialize output signal
    outputSignal = zeros(size(app.y));
    
 % Loop over each frequency band
    for i = 1:length(app.BandGains)
        % Get band edges
        f_low = app.BandEdges(i);
        f_high = app.BandEdges(i+1);

        % Normalize frequency
        Wn = [f_low f_high] / (app.fs / 2);
        if f_low == 0
            Wn = f_high / (app.fs / 2); % Lowpass
        end

       if strcmp(app.FilterType, 'FIR')
             app.FilterSubType = app.FilterTypeFIR;  % e.g., 'Hamming', 'Blackman', etc.
        else
             app.FilterSubType = app.FilterTypeIIR;  % e.g., 'Butterworth', 'Chebyshev I', etc.
        end
        
        [b, a] = app.designBandFilter(app.FilterType, app.FilterSubType, Wn, app.FilterOrder);

        % Filter signal and apply gain
        bandSignal = filter(b, a, app.y) * app.BandGains(i);

        % Add to output signal
        outputSignal = outputSignal + bandSignal;
    end

    % Store output and plot
    app.OutputSignal = outputSignal;
 % Plot
    figure;
    subplot(2,1,1);
    plot(app.t, app.y);
    title('Original Signal');
    xlabel('Time'); ylabel('Amplitude');

    subplot(2,1,2); 
    plot(app.t, outputSignal);
    title('Filtered Signal');
    xlabel('Time'); ylabel('Amplitude');
       end

       function [b, a] = designBandFilter(app, ~, subtype, Wn, order)
           disp(app.FilterType);
    if strcmp(app.FilterType, 'FIR')
        window = lower(subtype); % 'hamming', 'hanning', 'blackman'
        if isscalar(Wn)
            b = fir1(order, Wn, 'low', window);
        else
            b = fir1(order, Wn, 'bandpass', window);
        end
        a = 1;

    elseif strcmp(app.FilterType, 'IIR')
        if strcmp(subtype, 'Butterworth')
            [b, a] = butter(order, Wn, app.getFilterType(Wn));
        elseif strcmp(subtype, 'Chebyshev I')
            [b, a] = cheby1(order, 1, Wn, app.getFilterType(Wn)); % 1 dB ripple
        elseif strcmp(subtype, 'Chebyshev II')
            [b, a] = cheby2(order, 20, Wn, app.getFilterType(Wn)); % 20 dB stopband
        else
            error('Unknown IIR subtype');
        end

    else
        error('Unknown filter type');
    end
end

function type = getFilterType(~,Wn)
    if isscalar(Wn)
        type = 'low';
    else
        type = 'bandpass';
    end
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [FileName,FilePath]=uigetfile({'*.wav'});
           fullPath = [FilePath FileName];
           app.LocationEditField.Value = fullPath;
           [app.y,app.fm] = audioread(app.LocationEditField.Value);
           fprintf('The value of Sampling Frequency is %.2f\n', app.fm);
           app.y = app.y(:,1);   
           app.y = transpose(app.y);
           app.Ns = length(app.y);
           app.t = linspace(0, app.Ns/app.fm, app.Ns);
           %app.fm = app.fs/2;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            gains = [app.gain_170.Value app.gain_300.Value app.gain_610.Value app.gain_1005.Value app.gain_3k.Value app.gain_6k.Value app.gain_12k.Value app.gain_14k.Value app.gain_20k.Value];  
            app.BandGains = db2mag(gains);

            app.BandEdges = [0,200,500,800,1200,3000,6000,12000,16000,20000];                                 
            x =  str2double(app.BandtoPlotDropDown.Value);
            
            app.applyFilter();
        end

        % Callback function
        function samplingrateEditFieldValueChanged(app, event)

            
        end

        % Callback function
        function ResetButtonPushed(app, event)
           
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %Time Domain
            plot(app.UIAxes,app.t,app.y) % elly taht 3la el shemal
            t_new = linspace(0,length(app.new_signal)/app.newfs,length(app.new_signal));
            plot(app.UIAxes2,t_new,app.new_signal); % elly fo2 3la el yemin
            
            %Frequency Domain           
            L1 = length(app.y);
            L2 = length(app.new_signal);
            f_xaxis1 = linspace(-app.fs/2,app.fs/2,L1);
            f_xaxis2 = linspace(-app.newfs/2,app.newfs/2,L2);

            %Frequency Magnitude            
            fmag1 = abs(fftshift(fft(app.y))/app.fs); %we divide by L1 to normalize            
            plot(app.UIAxes4,f_xaxis1,fmag1); % 3la el yemin taht khales
            
             % we divide by L2 to normalize
            fmag2 = abs(fftshift(fft(app.new_signal))/app.newfs);             
            plot(app.UIAxes3,f_xaxis2,fmag2); % 3la el shemal taht khales


            %Frequency Phase   
            fphase1 = angle(fftshift(fft(app.y)));  
            plot(app.UIAxes6,f_xaxis1,fphase1); % 3la el yemin taht khales
            fphase2 = angle(fftshift(fft(app.new_signal)));
            plot(app.UIAxes5,f_xaxis2,fphase2); % 3la el shemal taht khales
                  
        end

        % Callback function
        function ApplynewfiltersButtonPushed(app, event)
            gains = [app.gain_170.Value app.gain_300.Value app.gain_610.Value app.gain_1005.Value app.gain_3k.Value app.gain_6k.Value app.gain_12k.Value app.gain_14k.Value app.gain_20k.Value];                       
            app.BandGains = db2mag(gains);            
            app.newfs = app.SamplingRateEditField.Value();
            app.BandEdges = [0,200,500,800,1200,3000,6000,12000,16000,20000];
            app.new_signal =zeros(1,length(app.y));

            if app.FilterTypeButtonGroup.SelectedObject == app.FIRButton
                for x = 1:9
                    if x==1
                        b = fir1(app.fir_order,170/app.fm);
                    else 
                        b = fir1(app.fir_order,[freq(x) freq(x+1)]/app.fm,'bandpass');
                    end
                    filteredSignal = filter(b,1,app.y); %filtered signal in time domain
                    app.new_signal = app.new_signal + (filteredSignal*app.BandGains(x));
                end
            else                 
                
                for x = 1:9

                     if x==1
                         [b,a] = butter(app.iir_order,170/app.fm);
                     else 
                         [b,a] = butter(app.iir_order,[freq(x) freq(x+1)]/app.fm,'bandpass');
                     end

                    filteredSignal = filter(b,a,app.y);                    
                    app.new_signal = app.new_signal + (filteredSignal*app.BandGains(x));
                end
            end
            if strcmpi(app.newfs,'Enter new Sampling rate') == 1
                app.newfs = app.fs;
            else
                app.newfs = str2double(app.newfs);
            end
            %app.new_signal = resample(app.new_signal,app.newfs,app.fs); 
                
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite("output_file_half.wav",app.new_signal, app.newfs/2);
            audiowrite("output_file_double.wav",app.new_signal, app.newfs*2);

            
        end

        % Button pushed function: doubleFsButton
        function doubleFsButtonPushed(app, event)
            app.newfs = app.fs*2;
            sound(app.y, app.newfs );
            disp("Playing signal at double the sampling frequency...");

            app.plot();
           
            
        end

        % Button pushed function: halfFsButton
        function halfFsButtonPushed(app, event)

            app.newfs = app.fs / 2;
       
        % Play the signal at half the original sampling frequency
        sound(app.y, app.newfs);

         app.plot();
        
                
        end

        % Callback function
        function SamplingRateEditFieldValueChanged(app, event)

          
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.SamplingRateEditField_2.ValueDisplayFormat = '%.0f';
             value = app.SamplingRateEditField_2.Value;  % Get the value from the Edit Field

    % Check if the value is valid (numeric and positive)
            if ~isnumeric(value) || isnan(value) || value <= 0
        uialert(app.UIFigure, ...
                'Sampling rate must be a positive number.', ...
                'Invalid Input');
        app.SamplingRateEditField_2.Value = 44100;  % Reset to default if invalid
            else
        app.fs = value;  % Store valid value
        fprintf("Output Sampling Rate: %d\n", app.fs);
            end
        end

        % Callback function
        function SamplingRateEditField_2ValueChanged(app, event)
          
            
        end

        % Value changed function: SamplingRateEditField_2
        function SamplingRateEditField_2ValueChanged2(app, event)
        
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
                if selectedButton == app.StandardButton
        % User selected Standard mode
        disp("Standard Mode Selected");
        app.Mode = "Standard";
        
             elseif selectedButton == app.CustomButton
        % User selected Custom mode
        disp("Custom Mode Selected");
        app.Mode = "Custom";
        x=app.showCustomBandDialog();
        waitfor(x);
        app.showCustomGainDialogBox();
        end
          
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedFilter = app.FilterTypeButtonGroup.SelectedObject;
                if selectedFilter == app.FIRButton
                 app.FilterType = 'FIR';
                 disp("FIR filter Selected");
                 app.FilterTypeFIR=app.getFIRFilterType();
                 elseif selectedFilter == app.IIRButton
                disp("IIR filter Selected");
                app.FilterType = 'IIR';
                app.FilterTypeIIR = app.getIIRFilterType();
                end

              app.FilterOrder=app.getFilterOrder();
        end

        % Value changed function: BandtoPlotDropDown
        function BandtoPlotDropDownValueChanged(app, event)
            value = app.BandtoPlotDropDown.Value;
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.y) || isempty(app.fs)
        uialert(app.UIFigure, 'Signal or sampling frequency not set.', 'Playback Error');
        return;
    end

    sound(app.y, app.fs);
    disp("Playing signal ...");
    % Time-Domain Plot
figure;
subplot(2,1,1);
plot(app.t, app.y);
xlabel('Time (s)');
ylabel('Amplitude');
title('Input Signal - Time Domain');

% Frequency-Domain Plot
L = length(app.y);
f_axis = linspace(-app.fs/2, app.fs/2, L);
Y_fft = fftshift(fft(app.y)/L); % Normalize
fmag = abs(Y_fft);

subplot(2,1,2);
plot(f_axis, fmag);
xlabel('Frequency (Hz)');
ylabel('Magnitude');
title('Input Signal - Frequency Domain');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed2(app, event)
             % Clear all axes
            cla(app.InputTimeAxes);
            cla(app.OutputTimeAxes);
            cla(app.InputFreqAxes);
            cla(app.OutputFreqAxes);
             app.BandGains = ones(1,9);
            app.gain_170.Value = 0;
            app.gain_300.Value = 0;
            app.gain_610.Value = 0;
            app.gain_1005.Value = 0;
            app.gain_3k.Value = 0;
            app.gain_6k.Value = 0;
            app.gain_12k.Value = 0;
            app.gain_14k.Value = 0;
            app.gain_20k.Value = 0;
            % Reset output signal
            app.OutputSignal = [];  
              % Display reset confirmation
            disp('Application reset: Output signal cleared, gains zeroed.');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.FIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.IIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Panel = uipanel(app.UIFigure);
            app.FrequencyBandGainsPanel = uipanel(app.Panel);
            app.FrequencyBandGainsPanel.Title = 'Frequency Band Gains';
            app.PlotButton = uibutton(app.FrequencyBandGainsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.HzLabel_3 = uilabel(app.FrequencyBandGainsPanel);
            app.HzLabel_3.Text = '800-1200Hz';
            app.gain_1005 = uislider(app.FrequencyBandGainsPanel);
            app.gain_1005.Limits = [-12 12];
            app.gain_1005.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.gain_1005.MajorTickLabels = {'-12,', '-9,', '-6,', '-3,', '0,', '3,', '6,', '9,', '12'};
            app.gain_1005.MinorTicks = [-12 -11.4 -10.8 -10.2 -9.6 -9 -8.4 -7.8 -7.2 -6.6 -6 -5.4 -4.8 -4.2 -3.6 -3 -2.4 -1.8 -1.2 -0.6 0 0.6 1.2 1.8 2.4 3 3.6 4.2 4.8 5.4 6 6.6 7.2 7.8 8.4 9 9.6 10.2 10.8 11.4 12];
            app.KHzLabel = uilabel(app.FrequencyBandGainsPanel);
            app.KHzLabel.Text = '1.2-3 KHz';
            app.gain_3k = uislider(app.FrequencyBandGainsPanel);
            app.gain_3k.Limits = [-12 12];
            app.ResetButton = uibutton(app.FrequencyBandGainsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed2, true);
            app.ResetButton.Text = 'Reset';
            app.FreuquencyDomainPanel = uipanel(app.Panel);
            app.FreuquencyDomainPanel.Title = 'Freuquency Domain';
            app.UIAxes3 = uiaxes(app.FreuquencyDomainPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'time(s)')
            ylabel(app.UIAxes3, 'magnitude')
            app.UIAxes4 = uiaxes(app.FreuquencyDomainPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'time(s)')
            ylabel(app.UIAxes4, 'magnitude')
            app.UIAxes6 = uiaxes(app.FreuquencyDomainPanel);
            xlabel(app.UIAxes6, 'time(s)')
            ylabel(app.UIAxes6, 'phase')
            app.UIAxes5 = uiaxes(app.FreuquencyDomainPanel);
            title(app.UIAxes5, {''; ''})
            xlabel(app.UIAxes5, 'time(s)')
            ylabel(app.UIAxes5, 'phase')
            app.MagnitudeLabel = uilabel(app.FreuquencyDomainPanel);
            app.MagnitudeLabel.Text = 'Magnitude';
            app.PhaseLabel = uilabel(app.FreuquencyDomainPanel);
            app.PhaseLabel.Text = 'Phase';
            app.AfterLabel = uilabel(app.FreuquencyDomainPanel);
            app.AfterLabel.Text = 'After';
            app.BeforeLabel_2 = uilabel(app.FreuquencyDomainPanel);
            app.BeforeLabel_2.Text = 'Before';
            app.HzLabel = uilabel(app.Panel);
            app.HzLabel.Text = '0-200Hz';
            app.gain_170 = uislider(app.Panel);
            app.gain_170.Limits = [-12 12];
            app.Label = uilabel(app.Panel);
            app.Label.Text = '200-500Hz';
            app.gain_300 = uislider(app.Panel);
            app.gain_300.Limits = [-12 12];
            app.HzLabel_2 = uilabel(app.Panel);
            app.HzLabel_2.Text = '500-800Hz';
            app.gain_610 = uislider(app.Panel);
            app.gain_610.Limits = [-12 12];
            app.KHzLabel_3 = uilabel(app.Panel);
            app.KHzLabel_3.Text = '3-6 KHz';
            app.gain_6k = uislider(app.Panel);
            app.gain_6k.Limits = [-12 12];
            app.KHzLabel_2 = uilabel(app.Panel);
            app.KHzLabel_2.Text = '6-12 KHz';
            app.gain_12k = uislider(app.Panel);
            app.gain_12k.Limits = [-12 12];
            app.KhzLabel = uilabel(app.Panel);
            app.KhzLabel.Text = '12-16Khz';
            app.gain_14k = uislider(app.Panel);
            app.gain_14k.Limits = [-12 12];
            app.KHzLabel_4 = uilabel(app.Panel);
            app.KHzLabel_4.Text = '16-20 KHz';
            app.gain_20k = uislider(app.Panel);
            app.gain_20k.Limits = [-12 12];
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.StandardButton = uiradiobutton(app.ModeButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.CustomButton = uiradiobutton(app.ModeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.halfFsButton = uibutton(app.UIFigure, 'push');
            app.halfFsButton.ButtonPushedFcn = createCallbackFcn(app, @halfFsButtonPushed, true);
            app.halfFsButton.Text = 'half Fs';
            app.doubleFsButton = uibutton(app.UIFigure, 'push');
            app.doubleFsButton.ButtonPushedFcn = createCallbackFcn(app, @doubleFsButtonPushed, true);
            app.doubleFsButton.Text = 'double Fs';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.LocationEditFieldLabel = uilabel(app.UIFigure);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.UIFigure, 'text');
            app.BandtoPlotDropDownLabel = uilabel(app.UIFigure);
            app.BandtoPlotDropDownLabel.Text = {'Band to Plot'; ''};
            app.BandtoPlotDropDown = uidropdown(app.UIFigure);
            app.BandtoPlotDropDown.Items = {'0-200 Hz', '200-500 Hz', '500-800 Hz', '800-1200 Hz', '1.2-3 KHz', '3-6 KHz', '6-12 KHz', '12-16 KHz', '16-20 KHz'};
            app.BandtoPlotDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BandtoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @BandtoPlotDropDownValueChanged, true);
            app.BandtoPlotDropDown.Value = '3';
            app.SamplingRateEditField_2Label = uilabel(app.UIFigure);
            app.SamplingRateEditField_2Label.Text = 'Sampling Rate';
            app.SamplingRateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField_2.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditField_2ValueChanged2, true);
            app.AxisPanel = uipanel(app.UIFigure);
            app.AxisPanel.Title = 'Axis';
            app.InputTimeAxes = uiaxes(app.AxisPanel);
            title(app.InputTimeAxes, 'Input Signal - Time Domain')
            xlabel(app.InputTimeAxes, 'X')
            ylabel(app.InputTimeAxes, 'Y')
            zlabel(app.InputTimeAxes, 'Z')
            app.OutputTimeAxes = uiaxes(app.AxisPanel);
            title(app.OutputTimeAxes, 'Output Signal - Time Domain')
            xlabel(app.OutputTimeAxes, 'X')
            ylabel(app.OutputTimeAxes, 'Y')
            zlabel(app.OutputTimeAxes, 'Z')
            app.InputFreqAxes = uiaxes(app.AxisPanel);
            title(app.InputFreqAxes, 'Input Signal - Frequency Domain')
            xlabel(app.InputFreqAxes, 'X')
            ylabel(app.InputFreqAxes, 'Y')
            zlabel(app.InputFreqAxes, 'Z')
            app.OutputFreqAxes = uiaxes(app.AxisPanel);
            title(app.OutputFreqAxes, 'Output Signal - Frequency Domain')
            xlabel(app.OutputFreqAxes, 'X')
            ylabel(app.OutputFreqAxes, 'Y')
            zlabel(app.OutputFreqAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end